classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ListadeaudiosButtonGroup      matlab.ui.container.ButtonGroup
        PodcastButton                 matlab.ui.control.ToggleButton
        AnuncioButton                 matlab.ui.control.ToggleButton
        MsicaButton                   matlab.ui.control.ToggleButton
        RuidoLabel                    matlab.ui.control.Label
        ruido                         matlab.ui.control.Knob
        FrecuenciadecorteSliderLabel  matlab.ui.control.Label
        fc1                           matlab.ui.control.Slider
        tipodefiltro                  matlab.ui.container.ButtonGroup
        pasa_bajo                     matlab.ui.control.RadioButton
        pasa_banda                    matlab.ui.control.RadioButton
        pasa_alto                     matlab.ui.control.RadioButton
        Label                         matlab.ui.control.Label
        fc2                           matlab.ui.control.Slider
        CalcularButton                matlab.ui.control.Button
        PLAY_audio_original_t         matlab.ui.control.Button
        PLAY_audio_filtrado_t         matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        FrecuenciademuestreoLabel     matlab.ui.control.Label
        Ax_audio_original_t           matlab.ui.control.UIAxes
        Ax_audio_filtrado_t           matlab.ui.control.UIAxes
        Ax_audio_filtro_f             matlab.ui.control.UIAxes
        Ax_audio_filtrado_f           matlab.ui.control.UIAxes
    end

        %Inicializo la variable name_audio con el valor "podcast_audio1"
        %pongo la variable en este bloque para compartir su valor en los
        %demas callbacks link:https://la.mathworks.com/matlabcentral/answers/580332-how-can-i-define-a-global-variable-in-app-designer
        %https://la.mathworks.com/help/matlab/creating_guis/share-data-across-callbacks-in-app-designer.html
        
    properties (Access = private)
        Property % Description
       
        
        name_audio = "podcast_audio1"; 
        tipo_filtro = 1;
        a_m;
        fs;
        a_lpf;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            
            %Obtenengo datos de la perilla ruido
            %cla(app.Ax_audio_original_t,"reset");
            %cla(app.Ax_audio_filtro_f,"reset");
            %cla(app.Ax_audio_filtrado_f,"reset");
            %cla(app.Ax_audio_filtrado_t,"reset");
            
            
            coef_ruido = app.ruido.Value;
            fc1 = app.fc1.Value;
            fc2 = app.fc2.Value;


            switch app.tipo_filtro
                case 1
                    fc = fc1;
                case 2
                    fc = [fc1,fc2];
                case 3
                    fc = fc1;
            end
            
            %% 
            %1=pasabajo
            %2=pasabanda
            %3=pasoalto
            %ejemplo
            %plot(f,filtro(2,[300,500],f));
            %plot(f,filtro(3,300,f))
            %plot(f,filtro(1,300,f))
            
            %function [app.fs,t,app.a_m,A_m,f,filtro,A_lpf,app.a_lpf] = procesamiento_audio(app.name_audio,coef_ruido,app.tipo_filtro,fc)
            
                [a,app.fs]=audioread(app.name_audio+".wav");
                info = audioinfo(app.name_audio+".wav");
                d=info.Duration;
                %canales de audio
                app.a_m = a(:,1); % canal izquierdo
                ruido = (rand(1,length(app.a_m))*coef_ruido)';
                app.a_m = app.a_m + ruido;
                
                Fs =info.SampleRate;            % Sampling frequency                    
                T = 1/Fs;             % Sampling period       
                L = length(a);             % Length of signal
                t = (0:L-1)*T;        % Time vector
                app.a_m=app.a_m.';
                
                %% Frecuencia
                A_m =fftshift( fft(app.a_m));
                f = linspace(-app.fs/2,app.fs/2,length(A_m));
                
                
                %% Filtro
                    switch app.tipo_filtro
                        case 1
                            
                            filtro=1.*((abs(f)<=fc)&((abs(f)>=0)));
                        case 2
                            
                            fc2=max(fc);
                            fc1=min(fc);
                            filtro=1.*((abs(f)<=fc2)&((abs(f)>=fc1)));
                        case 3
                            
                            filtro=1.*((abs(f)>=fc));
                        otherwise
                            
                            filtro=1.*((abs(f)<=300)&((abs(f)>=0)));
                    end 
                    %filtro=1.*((abs(f)<=1700)&((abs(f)>=200)));
                %filtro = filtro(2,[200 1700],f);
                
                
                %% Señal filtrada dominio de la frecuencia
                A_lpf=A_m.*filtro;
                
                
                %% Regresar al dominio de la frecuencia
                app.a_lpf = real(ifft(fftshift(A_lpf)));
            
            %end
            
            %%
            app.Label_2.Text = num2str(app.fs);
            
            %%
            plot(app.Ax_audio_original_t,t,app.a_m);


            hold(app.Ax_audio_filtro_f,'on');
            plot(app.Ax_audio_filtro_f,f,abs(A_m)/max(abs(A_m)));
            app.Ax_audio_filtro_f.XAxis.Exponent = 3;
            
            
            plot(app.Ax_audio_filtro_f,f,filtro,'r');
            hold(app.Ax_audio_filtro_f,'off');


            plot(app.Ax_audio_filtrado_f,f,abs(A_lpf)/max(abs(A_lpf)));
            app.Ax_audio_filtrado_f.XAxis.Exponent = 3;
            
            plot(app.Ax_audio_filtrado_t,t,app.a_lpf)
        end

        % Selection changed function: tipodefiltro
        function tipodefiltroSelectionChanged(app, event)
            selectedButton = app.tipodefiltro.SelectedObject;
            
            
            pasa_alto = app.pasa_alto.Value;
            pasa_bajo = app.pasa_bajo.Value;
            pasa_banda = app.pasa_banda.Value;
            
            if pasa_alto == 1
                app.tipo_filtro=3;
            elseif pasa_bajo == 1
                app.tipo_filtro=1;
            elseif pasa_banda == 1
                app.tipo_filtro = 2;
            else
                app.tipo_filtro = 1;
            end
            
            
            if app.tipo_filtro==2
                app.fc2.Visible = 'On';
                
            else
                app.fc2.Visible = 'Off';
            end
            
        end

        % Selection changed function: ListadeaudiosButtonGroup
        function ListadeaudiosButtonGroupSelectionChanged(app, event)
            selectedButton = app.ListadeaudiosButtonGroup.SelectedObject;
            
            Musica = app.MsicaButton.Value;
            Anuncio = app.AnuncioButton.Value;
            Podcast = app.PodcastButton.Value;
            
            if Musica ==1
                app.name_audio="musica_audio3";
            elseif Anuncio ==1
                app.name_audio="podcast_audio2";
            elseif Podcast == 1
                app.name_audio="podcast_audio1";
            else 
                app.name_audio="podcast_audio1";
            end
            
            
        end

        % Button pushed function: PLAY_audio_original_t
        function PLAY_audio_original_tPushed(app, event)
            sound(app.a_m,app.fs);
        end

        % Button pushed function: PLAY_audio_filtrado_t
        function PLAY_audio_filtrado_tButtonPushed(app, event)
            sound(app.a_lpf,app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListadeaudiosButtonGroup = uibuttongroup(app.UIFigure);
            app.ListadeaudiosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ListadeaudiosButtonGroupSelectionChanged, true);
            app.ListadeaudiosButtonGroup.Title = 'Lista de audios';
            app.PodcastButton = uitogglebutton(app.ListadeaudiosButtonGroup);
            app.PodcastButton.Text = 'Podcast ';
            app.PodcastButton.Value = true;
            app.AnuncioButton = uitogglebutton(app.ListadeaudiosButtonGroup);
            app.AnuncioButton.Text = 'Anuncio';
            app.MsicaButton = uitogglebutton(app.ListadeaudiosButtonGroup);
            app.MsicaButton.Text = 'Mùsica';
            app.RuidoLabel = uilabel(app.UIFigure);
            app.RuidoLabel.Text = 'Ruido';
            app.ruido = uiknob(app.UIFigure, 'continuous');
            app.ruido.Limits = [0.05 0.15];
            app.ruido.Value = 0.05;
            app.FrecuenciadecorteSliderLabel = uilabel(app.UIFigure);
            app.FrecuenciadecorteSliderLabel.Text = {'Frecuencia'; 'de corte '};
            app.fc1 = uislider(app.UIFigure);
            app.fc1.Limits = [0 5000];
            app.tipodefiltro = uibuttongroup(app.UIFigure);
            app.tipodefiltro.SelectionChangedFcn = createCallbackFcn(app, @tipodefiltroSelectionChanged, true);
            app.tipodefiltro.Title = 'Tipo de filtro';
            app.pasa_bajo = uiradiobutton(app.tipodefiltro);
            app.pasa_bajo.Text = 'Pasa bajo';
            app.pasa_bajo.Value = true;
            app.pasa_banda = uiradiobutton(app.tipodefiltro);
            app.pasa_banda.Text = 'Pasa banda';
            app.pasa_alto = uiradiobutton(app.tipodefiltro);
            app.pasa_alto.Text = 'Pasa alto';
            app.Label = uilabel(app.UIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '';
            app.fc2 = uislider(app.UIFigure);
            app.fc2.Limits = [0 5000];
            app.fc2.Visible = 'off';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.PLAY_audio_original_t = uibutton(app.UIFigure, 'push');
            app.PLAY_audio_original_t.ButtonPushedFcn = createCallbackFcn(app, @PLAY_audio_original_tPushed, true);
            app.PLAY_audio_original_t.Text = {'PLAY'; ''};
            app.PLAY_audio_filtrado_t = uibutton(app.UIFigure, 'push');
            app.PLAY_audio_filtrado_t.ButtonPushedFcn = createCallbackFcn(app, @PLAY_audio_filtrado_tButtonPushed, true);
            app.PLAY_audio_filtrado_t.Text = 'PLAY';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.FrecuenciademuestreoLabel = uilabel(app.UIFigure);
            app.FrecuenciademuestreoLabel.Text = {'Frecuencia de muestreo'; ''};
            app.Ax_audio_original_t = uiaxes(app.UIFigure);
            title(app.Ax_audio_original_t, 'Audio original')
            xlabel(app.Ax_audio_original_t, 'Tiempo [s]')
            ylabel(app.Ax_audio_original_t, 'Amplitud')
            zlabel(app.Ax_audio_original_t, 'Z')
            app.Ax_audio_filtrado_t = uiaxes(app.UIFigure);
            title(app.Ax_audio_filtrado_t, 'Audio filtrado')
            xlabel(app.Ax_audio_filtrado_t, 'Tiempo [s]')
            ylabel(app.Ax_audio_filtrado_t, 'Amplitud')
            zlabel(app.Ax_audio_filtrado_t, 'Z')
            app.Ax_audio_filtro_f = uiaxes(app.UIFigure);
            title(app.Ax_audio_filtro_f, 'Audio y filtro')
            xlabel(app.Ax_audio_filtro_f, 'Frecuencia [Hz]')
            ylabel(app.Ax_audio_filtro_f, 'Amplitud')
            zlabel(app.Ax_audio_filtro_f, 'Z')
            app.Ax_audio_filtrado_f = uiaxes(app.UIFigure);
            title(app.Ax_audio_filtrado_f, 'Audio filtrado')
            xlabel(app.Ax_audio_filtrado_f, 'Frecuencia [Hz]')
            ylabel(app.Ax_audio_filtrado_f, 'Amplitud')
            zlabel(app.Ax_audio_filtrado_f, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end